classdef map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapUIFigure            matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        IconButtonGroup        matlab.ui.container.ButtonGroup
        SquareButton           matlab.ui.control.RadioButton
        TriangleButton         matlab.ui.control.RadioButton
        DogButton              matlab.ui.control.RadioButton
        VectorButton           matlab.ui.control.RadioButton
        FakeButton             matlab.ui.control.RadioButton
        GalleryButtonGroup     matlab.ui.container.ButtonGroup
        Rotate45ExpandButton   matlab.ui.control.RadioButton
        Rotate120Button        matlab.ui.control.RadioButton
        ContractStretchButton  matlab.ui.control.RadioButton
        CustomButton           matlab.ui.control.RadioButton
        FakeButton_2           matlab.ui.control.RadioButton
        IterateButton          matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        MatrixPanel            matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        EditField_2            matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        EditField_3            matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        EditField_4            matlab.ui.control.NumericEditField
        VectorPanel            matlab.ui.container.Panel
        XEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Xstart
        Ystart
        Transform = 1;
        PlotStyle
        Iterations = 0;
        Colors = {'y','m','c','r','g',[.6 0 .6],[1 .7 .2],[0 .6 .3],[.4 .4 .4]};
        MatrixPreload = [0 -1; 1 0];
        Vector = [1 1];
        
    end

    methods (Access = private)
    
        function polyPlot(app)
            cla(app.UIAxes)
            
            plot(app.UIAxes,0,0,'or')
            hold(app.UIAxes,'on')
            
            plot(app.UIAxes,app.Xstart,app.Ystart)
            fill(app.UIAxes,app.Xstart,app.Ystart,'b')
            
            N = length(app.Xstart);
            xnew = zeros(N,1);
            ynew = zeros(N,1);
            Bx0 = max(abs(app.Xstart));
            By0 = max(abs(app.Ystart));
            
            xold = app.Xstart;
            yold = app.Ystart;
            
            C = 1;
            
            for j = 1: app.Iterations
                for i = 1: N
                    u = app.Transform*[xold(i);yold(i)];
                    xnew(i) = u(1); ynew(i) = u(2);
                end
                plot(app.UIAxes,xnew,ynew)
                
                fill(app.UIAxes,xnew,ynew,app.Colors{C})
                C = C+1;
                
                Bx(j) = max(abs(xnew)); By(j) = max(abs(ynew));
                xold = xnew; yold = ynew;
            end
            
        end
        
        
        function vecPlot(app)
            cla(app.UIAxes)
            
            app.Xstart = [0; app.Vector(1)];
            app.Ystart = [0; app.Vector(2)];
            
            plot(app.UIAxes,0,0,'or')
            hold(app.UIAxes,'on')
            
            plot(app.UIAxes,app.Xstart,app.Ystart,'Color','b','LineWidth',2)
            
            N = length(app.Xstart);
            xnew = zeros(N,1);
            ynew = zeros(N,1);
            Bx0 = max(abs(app.Xstart));
            By0 = max(abs(app.Ystart));
            
            xold = app.Xstart;
            yold = app.Ystart;
            
            C = 1;
            
            for j = 1: app.Iterations
                for i = 1: N
                    u = app.Transform*[xold(i);yold(i)];
                    xnew(i) = u(1); ynew(i) = u(2);
                end
                
                plot(app.UIAxes,xnew,ynew,'Color',app.Colors{C},'LineWidth',2)
                C = C+1;
                
                Bx(j) = max(abs(xnew)); By(j) = max(abs(ynew));
                xold = xnew; yold = ynew;
            end
        end
        
        
        function plotSelect(app)
            switch app.PlotStyle
                case 'poly'
                    polyPlot(app)
                case 'vec'
                    vecPlot(app)
            end
        end
        
        function activateGallery(app)
            app.Rotate45ExpandButton.Enable = 'on';
            app.Rotate120Button.Enable = 'on';
            app.ContractStretchButton.Enable = 'on';
            app.CustomButton.Enable = 'on';
            
            app.IterateButton.Enable = 'on';
        end
    
        function deactivateGallery(app)
            app.Rotate45ExpandButton.Enable = 'off';
            app.Rotate120Button.Enable = 'off';
            app.ContractStretchButton.Enable = 'off';
            app.CustomButton.Enable = 'off';
        end
        
        function activateCustomMatrix(app)
            app.EditField.Enable = 'on';
            app.EditField_2.Enable = 'on';
            app.EditField_3.Enable = 'on';
            app.EditField_4.Enable = 'on';
        end
        
        function deactivateCustomMatrix(app)
            app.EditField.Enable = 'off';
            app.EditField_2.Enable = 'off';
            app.EditField_3.Enable = 'off';
            app.EditField_4.Enable = 'off';
        end
        
        function updateEditFields(app)
            app.EditField.Value = app.Transform(1,1);
            app.EditField_2.Value = app.Transform(1,2);
            app.EditField_3.Value = app.Transform(2,1);
            app.EditField_4.Value = app.Transform(2,2);
        end
        
        function activateCustomVector(app)
            app.XEditField.Enable = 'on';
            app.YEditField.Enable = 'on';
        end
    
        function deactivateCustomVector(app)
            app.XEditField.Enable = 'off';
            app.YEditField.Enable = 'off';
        end
    end


    methods (Access = private)

        % Selection changed function: IconButtonGroup
        function IconButtonGroupSelectionChanged(app, event)
            selectedButton = app.IconButtonGroup.SelectedObject;
            
            activateGallery(app)
            deactivateCustomVector(app)
            
            switch selectedButton.Text
                case 'Square'
                    app.Xstart = [0; 1; 1; 0; 0];
                    app.Ystart = [0; 0; 1; 1; 0];
                    app.PlotStyle = 'poly';
                case 'Triangle'
                    app.Xstart = [0; 1; 1.0; 0];
                    app.Ystart = [0; 1; 1.5; 0];
                    app.PlotStyle = 'poly';
                case 'Vector:'
                    activateCustomVector(app)
                    app.Vector(1) = app.XEditField.Value;
                    app.Vector(2) = app.YEditField.Value;
                    app.PlotStyle = 'vec';
                case 'Dog'
                    app.Xstart = [0.8711 1.0166 1.1751 1.7441 2.0674 1.9413 1.8443 1.7861 1.7279 1.2818 1.1977 1.1686 1.0490 1.0360 0.8679 0.8711]'/2;
                    app.Ystart = [1.6176 1.8261 1.6097 1.5595 1.4302 1.3378 1.3378 1.0422 1.3326 1.3378 1.0106 1.3194 1.4012 1.5886 1.6176 1.6176]'/2;
                    app.PlotStyle = 'poly';
            end
            
            plotSelect(app)
            
        end

        % Selection changed function: GalleryButtonGroup
        function GalleryButtonGroupSelectionChanged(app, event)
            selectedButton = app.GalleryButtonGroup.SelectedObject;
            
            deactivateCustomMatrix(app)
            
            switch selectedButton.Text
                case 'Rotate 45 & Expand'
                    theta = pi/4;
                    app.Transform = 1.5*[cos(theta) -sin(theta); sin(theta) cos(theta)];
                    updateEditFields(app)
                    plotSelect(app)
                case 'Rotate 120'
                    theta = 2*pi/3;
                    app.Transform = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                    updateEditFields(app)
                    plotSelect(app)
                case 'Contract / Stretch'
                    app.Transform = [0.3 0 ; 0 1.2];
                    updateEditFields(app)
                    plotSelect(app)
                case 'Custom:'
                    activateCustomMatrix(app)
                    app.Transform = [app.EditField.Value app.EditField_2.Value ; app.EditField_3.Value app.EditField_4.Value];
                    plotSelect(app)
            end
        end

        % Button pushed function: IterateButton
        function IterateButtonPushed(app, event)
            if app.Iterations == 8
                app.IterateButton.Enable = 'off';
            end
            
            app.Iterations = app.Iterations + 1;
            plotSelect(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            deactivateCustomMatrix(app)
            deactivateGallery(app)
            deactivateCustomVector(app)
            app.Iterations = 0;
            app.FakeButton.Value = true;
            app.FakeButton_2.Value = true;
            app.IterateButton.Enable = 'off';
            app.Transform = app.MatrixPreload;
            updateEditFields(app)
            app.XEditField.Value = 1;
            app.YEditField.Value = 1;
            app.Vector = [1 1];
            app.Transform = 1;
            cla(app.UIAxes)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Transform(1,1) = value;
            plotSelect(app)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Transform(1,2) = value;
            plotSelect(app)
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Transform(2,1) = value;
            plotSelect(app)
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Transform(2,2) = value;
            plotSelect(app)
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.Vector(1) = value;
            plotSelect(app)
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.Vector(2) = value;
            plotSelect(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapUIFigure = uifigure;
            app.MapUIFigure.Name = 'Map';
            app.UIAxes = uiaxes(app.MapUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-3 3];
            app.UIAxes.YLim = [-3 3];
            app.IconButtonGroup = uibuttongroup(app.MapUIFigure);
            app.IconButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IconButtonGroupSelectionChanged, true);
            app.IconButtonGroup.Title = 'Icon';
            app.SquareButton = uiradiobutton(app.IconButtonGroup);
            app.SquareButton.Text = 'Square';
            app.TriangleButton = uiradiobutton(app.IconButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.DogButton = uiradiobutton(app.IconButtonGroup);
            app.DogButton.Text = 'Dog';
            app.VectorButton = uiradiobutton(app.IconButtonGroup);
            app.VectorButton.Text = 'Vector:';
            app.FakeButton = uiradiobutton(app.IconButtonGroup);
            app.FakeButton.Enable = 'off';
            app.FakeButton.Visible = 'off';
            app.FakeButton.Text = 'Fake';
            app.FakeButton.Value = true;
            app.GalleryButtonGroup = uibuttongroup(app.MapUIFigure);
            app.GalleryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GalleryButtonGroupSelectionChanged, true);
            app.GalleryButtonGroup.Title = 'Gallery';
            app.Rotate45ExpandButton = uiradiobutton(app.GalleryButtonGroup);
            app.Rotate45ExpandButton.Enable = 'off';
            app.Rotate45ExpandButton.Text = 'Rotate 45 & Expand';
            app.Rotate120Button = uiradiobutton(app.GalleryButtonGroup);
            app.Rotate120Button.Enable = 'off';
            app.Rotate120Button.Text = 'Rotate 120';
            app.ContractStretchButton = uiradiobutton(app.GalleryButtonGroup);
            app.ContractStretchButton.Enable = 'off';
            app.ContractStretchButton.Text = 'Contract / Stretch';
            app.CustomButton = uiradiobutton(app.GalleryButtonGroup);
            app.CustomButton.Enable = 'off';
            app.CustomButton.Text = 'Custom:';
            app.FakeButton_2 = uiradiobutton(app.GalleryButtonGroup);
            app.FakeButton_2.Enable = 'off';
            app.FakeButton_2.Visible = 'off';
            app.FakeButton_2.Text = 'Fake';
            app.FakeButton_2.Value = true;
            app.IterateButton = uibutton(app.MapUIFigure, 'push');
            app.IterateButton.ButtonPushedFcn = createCallbackFcn(app, @IterateButtonPushed, true);
            app.IterateButton.Enable = 'off';
            app.IterateButton.Text = 'Iterate';
            app.ResetButton = uibutton(app.MapUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MatrixPanel = uipanel(app.MapUIFigure);
            app.MatrixPanel.Title = 'Matrix';
            app.Label = uilabel(app.MatrixPanel);
            app.Label.Text = '[1,1]';
            app.EditField = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.3f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.Label_2 = uilabel(app.MatrixPanel);
            app.Label_2.Text = '[1,2]';
            app.EditField_2 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.3f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = -1;
            app.Label_3 = uilabel(app.MatrixPanel);
            app.Label_3.Text = '[2,1]';
            app.EditField_3 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.3f';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = 1;
            app.Label_4 = uilabel(app.MatrixPanel);
            app.Label_4.Text = '[2,2]';
            app.EditField_4 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.3f';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Enable = 'off';
            app.VectorPanel = uipanel(app.MapUIFigure);
            app.VectorPanel.Title = 'Vector';
            app.XEditFieldLabel = uilabel(app.VectorPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.VectorPanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.1f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Enable = 'off';
            app.XEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.VectorPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.VectorPanel, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.1f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Enable = 'off';
            app.YEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = map

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapUIFigure)
        end
    end
end